classdef alvyngrinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlvynGreenGrindingFrameworkV12UIFigure  matlab.ui.Figure
        mLabel                          matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        SurfaceRoughnessRzLabel         matlab.ui.control.Label
        SurfaceRoughnessCalculated      matlab.ui.control.Label
        SurfaceRoughnessIcon            matlab.ui.control.Image
        BestModelIdentifier             matlab.ui.control.Label
        MLModelIdentifier               matlab.ui.control.Label
        ModelIdentifierIcon             matlab.ui.control.Image
        WheelSpeedValueSelector         matlab.ui.control.DropDown
        WheelSpeedLabel                 matlab.ui.control.Label
        DepthofDressingValueSelector    matlab.ui.control.DropDown
        DepthofDressingLabel            matlab.ui.control.Label
        DressingFeedLabel               matlab.ui.control.Label
        DressingSpeedVaulueSelector     matlab.ui.control.DropDown
        DresserTypeValueSelector        matlab.ui.control.DropDown
        DresserTypeLabel                matlab.ui.control.Label
        WorkSpeedValueSelector          matlab.ui.control.DropDown
        WorkSpeedLabel                  matlab.ui.control.Label
        DepthofGrindingValueSelector    matlab.ui.control.DropDown
        DepthofGrindingLabel            matlab.ui.control.Label
        SpecificRemovalRateValueSelector  matlab.ui.control.DropDown
        SpecificRemovalRateLabel        matlab.ui.control.Label
        AlloyWorkpieceTypeSelector      matlab.ui.control.DropDown
        AlloyWorkpieceLabel             matlab.ui.control.Label
        GrindingWheelTypeLabel          matlab.ui.control.Label
        GrindingWheelTypeSelectorValue  matlab.ui.control.DropDown
        Calculator                      matlab.ui.control.Button
        CoolantTypeValueSelector        matlab.ui.control.DropDown
        CoolantTypeLabel                matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        MachineLearningProcess          matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description

        fileNameMain;
        fileLocation;
        allData;
        TrainingData;
        TestingData;
        Predictors;
        Responses;

        %% -------------------------------------------------------
        allDataFromSVR;
        allDataFromGPR;
        allDataFromEnsemble;
        allDataFromANN;
        allDataFromKNN;
        

        %% ----------------------------------------------------------------
        allModelNames = {'SVR'; 'GPR'; 'ANN'; 'Ensemble'; 'KNN'};
        allModelMBDFValue = zeros(5,1);
        bestModelIdentifierValue;
        messageForModelIdentifier;
        calculatedValue;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(0,'ScreenSize');
            formSize = app.AlvynGreenGrindingFrameworkV12UIFigure.Position;
            app.AlvynGreenGrindingFrameworkV12UIFigure.Position = [abs(screenSize(3) / 2.0 - formSize(3) / 2.0), ...
                                                           abs(screenSize(4) / 2.0 - formSize(4) / 2.0), ...
                                                           formSize(3), ...
                                                           formSize(4)];

            
        end

        % Button pushed function: MachineLearningProcess
        function MachineLearningProcessButtonPushed(app, event)

            app.MLModelIdentifier.Enable = "off";
            app.BestModelIdentifier.Enable = "off";
            app.ModelIdentifierIcon.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'media/', 'calculation.gif');
            app.MachineLearningProcess.Enable = "off";
            pause(1);
            
            [app.allData, app.TrainingData, app.TestingData] = readingDefaultData();
            [app.allDataFromSVR, MBDF, ~] = SVR(app.TrainingData, app.TestingData);
            app.allModelMBDFValue(1) = MBDF;
            %disp(message);
            [app.allDataFromGPR, MBDF, ~] = GPR(app.TrainingData, app.TestingData);
            app.allModelMBDFValue(2) = MBDF;
            %disp(message);
            [app.allDataFromANN, MBDF, ~] = ANN(app.TrainingData, app.TestingData);
            app.allModelMBDFValue(3) = MBDF;
            %disp(message);
            [app.allDataFromEnsemble, MBDF, ~] = Ensemble(app.TrainingData, app.TestingData);
            app.allModelMBDFValue(4) = MBDF;
            %disp(message);
            [app.allDataFromKNN, MBDF, ~] = KNearest(app.TrainingData, app.TestingData);
            app.allModelMBDFValue(5) = MBDF;
            %disp(message);

            [minValMBDF, app.bestModelIdentifierValue] = min(app.allModelMBDFValue);
            
            
            if(app.bestModelIdentifierValue == 1)
                [~, ~, app.messageForModelIdentifier] = SVR(app.TrainingData, app.TestingData);
            elseif (app.bestModelIdentifierValue == 2)
                [~, ~, app.messageForModelIdentifier] = GPR(app.TrainingData, app.TestingData);
            elseif (app.bestModelIdentifierValue == 3)
                [~, ~, app.messageForModelIdentifier] = ANN(app.TrainingData, app.TestingData);
            elseif (app.bestModelIdentifierValue == 4)
                [~, ~, app.messageForModelIdentifier] = Ensemble(app.TrainingData, app.TestingData);
            elseif (app.bestModelIdentifierValue == 5)
                [~, ~, app.messageForModelIdentifier] = KNearest(app.TrainingData, app.TestingData);
            end

            pause(1);
            app.BestModelIdentifier.Text = app.allModelNames(app.bestModelIdentifierValue);
            app.MLModelIdentifier.Enable = "on";
            app.BestModelIdentifier.Enable = "on";
            app.BestModelIdentifier.Tooltip = app.messageForModelIdentifier;
            app.ModelIdentifierIcon.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'media/', 'brain.png');
            app.MachineLearningProcess.Enable = "on";
            app.Calculator.Enable = "on";

        end

        % Button pushed function: Calculator
        function CalculatorPushed(app, event)
            
            app.SurfaceRoughnessIcon.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'media/', 'calculation.gif');
            pause(0.5);

            %% -----------------------------------------------------------
            %{
              
              List of all grinding parameters in the cumulative paper
              1. Types of Alloy
              2. Wheel Speed
              3. Work Speed
              4. Dresser Type
              5. Depth of Dressing 
              6. Dressing Speed
              7. Depth of Grinding
              8. Specific Removal Rate
              9. Coolant Type
              10. Grinding Wheel Type  

            %}
            
            TypesofAlloy = app.AlloyWorkpieceTypeSelector.ValueIndex;
            WheelSpeed   = str2double(app.WheelSpeedValueSelector.Value);
            WorkSpeed    = str2double(app.WorkSpeedValueSelector.Value);
            DresserType = 0;
            if(app.DresserTypeValueSelector.ValueIndex == 1 )
                DresserType = 1;
            elseif(app.DresserTypeValueSelector.ValueIndex == 2)
                DresserType = 4;
            end
            DepthofDressing = str2double(app.DepthofDressingValueSelector.Value);
            
            DressingSpeed = str2double(app.DressingSpeedVaulueSelector.Value);
            DepthofGrinding = str2double(app.DepthofGrindingValueSelector.Value);
            SpecificRemovalRate = str2double(app.SpecificRemovalRateValueSelector.Value);

            CoolantType = 0;
            if(app.CoolantTypeValueSelector.ValueIndex == 1)
                CoolantType = 24; % Fluid
            elseif(app.CoolantTypeValueSelector.ValueIndex == 2)
                CoolantType = 12; % Wet
            elseif(app.CoolantTypeValueSelector.ValueIndex == 3)
                CoolantType = 11; % MQL (Type 0)
            elseif(app.CoolantTypeValueSelector.ValueIndex == 4)
                CoolantType = 22; % MQL (Type 1)
            elseif(app.CoolantTypeValueSelector.ValueIndex == 5)
                CoolantType = 23; % MQL (Type 2)
            elseif(app.CoolantTypeValueSelector.ValueIndex == 6)
                CoolantType = 21; % Dry
            end

            GrindingWheelType = app.GrindingWheelTypeSelectorValue.ValueIndex;

            allCalculatingDataInput = [TypesofAlloy; ...
                                       WheelSpeed; ...
                                       WorkSpeed; ...
                                       DresserType; ...
                                       DepthofDressing; ...
                                       DressingSpeed; ...
                                       DepthofGrinding; ...
                                       SpecificRemovalRate; ...
                                       CoolantType; ...
                                       GrindingWheelType];

            %disp(allCalculatingDataInput);


            if(app.bestModelIdentifierValue == 1)
                %[~, ~, ~] = SVR(app.TrainingData, app.TestingData);
                [~, ~, ~, app.calculatedValue] = SVRCalculator(app.TrainingData, app.TestingData, allCalculatingDataInput);
            elseif (app.bestModelIdentifierValue == 2)
                %[~, ~, ~] = GPR(app.TrainingData, app.TestingData);
                [~, ~, ~, app.calculatedValue] = GPRCalculator(app.TrainingData, app.TestingData, allCalculatingDataInput);
            elseif (app.bestModelIdentifierValue == 3)
                %[~, ~, ~] = ANN(app.TrainingData, app.TestingData);
                [~, ~, ~, app.calculatedValue] = ANNCalculator(app.TrainingData, app.TestingData, allCalculatingDataInput);
            elseif (app.bestModelIdentifierValue == 4)
                %[~, ~, ~] = Ensemble(app.TrainingData, app.TestingData);
                [~, ~, ~, app.calculatedValue] = EnsembleCalculator(app.TrainingData, app.TestingData, allCalculatingDataInput);
            elseif (app.bestModelIdentifierValue == 5)
                %[~, ~, ~] = KNearest(app.TrainingData, app.TestingData);
                [~, ~, ~, app.calculatedValue] = KNearestCalculator(app.TrainingData, app.TestingData, allCalculatingDataInput);
            end
            
            pause(0.5);
            app.SurfaceRoughnessIcon.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'media/', 'layers.png');
            formattedStr = sprintf('%.2f', app.calculatedValue);
            app.SurfaceRoughnessCalculated.Text = formattedStr;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AlvynGreenGrindingFrameworkV12UIFigure = uifigure('Visible', 'off');
            app.AlvynGreenGrindingFrameworkV12UIFigure.Name = 'Alvyn Green Grinding Framework  V1.2';
            app.AlvynGreenGrindingFrameworkV12UIFigure.Icon = fullfile(pathToMLAPP, 'media', 'iconAPPV2.png');
            app.Image6 = uiimage(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'media', 'Cumulative Paper-Page-3.drawio.png');
            app.MachineLearningProcess = uibutton(app.AlvynGreenGrindingFrameworkV12UIFigure, 'push');
            app.MachineLearningProcess.ButtonPushedFcn = createCallbackFcn(app, @MachineLearningProcessButtonPushed, true);
            app.MachineLearningProcess.Icon = fullfile(pathToMLAPP, 'media', 'processIcon.png');
            app.MachineLearningProcess.Text = '';
            app.Button_4 = uibutton(app.AlvynGreenGrindingFrameworkV12UIFigure, 'push');
            app.Button_4.Icon = fullfile(pathToMLAPP, 'media', 'settingsIcon.png');
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.CoolantTypeLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.CoolantTypeLabel.Text = 'Coolant Type';
            app.CoolantTypeValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.CoolantTypeValueSelector.Items = {'Fluid', 'Wet', 'MQL (Type 0)', 'MQL (Type 1)', 'MQL (Type 2)', 'Dry'};
            app.CoolantTypeValueSelector.Value = 'Fluid';
            app.Calculator = uibutton(app.AlvynGreenGrindingFrameworkV12UIFigure, 'push');
            app.Calculator.ButtonPushedFcn = createCallbackFcn(app, @CalculatorPushed, true);
            app.Calculator.Icon = fullfile(pathToMLAPP, 'media', 'calculator.png');
            app.Calculator.Enable = 'off';
            app.Calculator.Text = '';
            app.GrindingWheelTypeSelectorValue = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.GrindingWheelTypeSelectorValue.Items = {'Al2O3 WA60K9V', 'Al2O3 180K6', 'Al2O3 80L6-2', 'Al2O3 120I8'};
            app.GrindingWheelTypeSelectorValue.Value = 'Al2O3 WA60K9V';
            app.GrindingWheelTypeLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.GrindingWheelTypeLabel.Text = 'Grinding Wheel Type';
            app.AlloyWorkpieceLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.AlloyWorkpieceLabel.Text = 'Alloy Workpiece';
            app.AlloyWorkpieceTypeSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.AlloyWorkpieceTypeSelector.Items = {'Inconel® 738', 'Aluminum A6061', 'Steel UNS34700'};
            app.AlloyWorkpieceTypeSelector.Value = 'Inconel® 738';
            app.SpecificRemovalRateLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.SpecificRemovalRateLabel.Text = 'Specific Removal Rate';
            app.SpecificRemovalRateValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.SpecificRemovalRateValueSelector.Items = {'7.5', '22.5', '37.5', '52.5', '67.5', '82.5', '97.5', '135', '450'};
            app.SpecificRemovalRateValueSelector.Value = '7.5';
            app.DepthofGrindingLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DepthofGrindingLabel.Text = 'Depth of Grinding';
            app.DepthofGrindingValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DepthofGrindingValueSelector.Items = {'5', '15', '25', '30', '35', '45', '55', '65'};
            app.DepthofGrindingValueSelector.Value = '5';
            app.WorkSpeedLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.WorkSpeedLabel.Text = 'Work Speed';
            app.WorkSpeedValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.WorkSpeedValueSelector.Items = {'1.5', '4.5', '15'};
            app.WorkSpeedValueSelector.Value = '1.5';
            app.DresserTypeLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DresserTypeLabel.Text = 'Dresser Type';
            app.DresserTypeValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DresserTypeValueSelector.Items = {'Single Point', 'Quadra Point'};
            app.DresserTypeValueSelector.Value = 'Single Point';
            app.DressingSpeedVaulueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DressingSpeedVaulueSelector.Items = {'50', '85', '150', '213', '426', '600'};
            app.DressingSpeedVaulueSelector.Value = '50';
            app.DressingFeedLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DressingFeedLabel.Text = 'Dressing Feed';
            app.DepthofDressingLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DepthofDressingLabel.Text = 'Depth of Dressing';
            app.DepthofDressingValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.DepthofDressingValueSelector.Items = {'2', '5', '10', '20', '40'};
            app.DepthofDressingValueSelector.Value = '2';
            app.WheelSpeedLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.WheelSpeedLabel.Text = 'Wheel Speed';
            app.WheelSpeedValueSelector = uidropdown(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.WheelSpeedValueSelector.Items = {'30', '47'};
            app.WheelSpeedValueSelector.Value = '30';
            app.ModelIdentifierIcon = uiimage(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.ModelIdentifierIcon.ImageSource = fullfile(pathToMLAPP, 'media', 'brain.png');
            app.MLModelIdentifier = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.MLModelIdentifier.Text = 'ML Model';
            app.BestModelIdentifier = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.BestModelIdentifier.Tooltip = {'No best model identified yet!'};
            app.BestModelIdentifier.Text = '--';
            app.SurfaceRoughnessIcon = uiimage(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.SurfaceRoughnessIcon.ImageSource = fullfile(pathToMLAPP, 'media', 'layers.png');
            app.SurfaceRoughnessCalculated = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.SurfaceRoughnessCalculated.Text = '--';
            app.SurfaceRoughnessRzLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.SurfaceRoughnessRzLabel.Text = 'Surface Roughness (Rz)';
            app.Image4 = uiimage(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'media', 'info.png');
            app.mLabel = uilabel(app.AlvynGreenGrindingFrameworkV12UIFigure);
            app.mLabel.Text = 'μm';
            % Show the figure after all components are created
            app.AlvynGreenGrindingFrameworkV12UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alvyngrinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlvynGreenGrindingFrameworkV12UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlvynGreenGrindingFrameworkV12UIFigure)
        end
    end
end